classdef ImageProcessingApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IPApplicationUIFigure           matlab.ui.Figure
        ImageProcessingApplicationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ImageAdjustmentTab              matlab.ui.container.Tab
        ResetSaveImageLabel_3           matlab.ui.control.Label
        ResultingImageLabel_2           matlab.ui.control.Label
        SelectedImageLabel_2            matlab.ui.control.Label
        BrightnessLabel                 matlab.ui.control.Label
        HueLabel                        matlab.ui.control.Label
        Slider_6                        matlab.ui.control.Slider
        Slider_4                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        SaturationLabel                 matlab.ui.control.Label
        ContrastLabel                   matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        TakeSelectAnImageButton         matlab.ui.control.Button
        AdjustTheImageLabel             matlab.ui.control.Label
        ChooseInputStyleLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ImageRestorationTab             matlab.ui.container.Tab
        ResultingImageLabel_3           matlab.ui.control.Label
        NoisyImageLabel                 matlab.ui.control.Label
        SelectedImageLabel_3            matlab.ui.control.Label
        ConstrainedLeastSquareFilterButton  matlab.ui.control.Button
        MedianFilterButton              matlab.ui.control.Button
        WienerFilterButton              matlab.ui.control.Button
        SaveResultingImageButton        matlab.ui.control.Button
        SpeckleButton                   matlab.ui.control.Button
        PoissonButton                   matlab.ui.control.Button
        GaussianButton                  matlab.ui.control.Button
        SPButton                        matlab.ui.control.Button
        ResetSaveImageLabel_4           matlab.ui.control.Label
        SaveNoisyImageButton            matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        ChooseRestorationMethodLabel    matlab.ui.control.Label
        ChooseNoiseStyleLabel           matlab.ui.control.Label
        TakeSelectAnImageButton_3       matlab.ui.control.Button
        ChooseInputStyleLabel_3         matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        ImageSegmentationTab            matlab.ui.container.Tab
        ChooseCmeanKnobLabel            matlab.ui.control.Label
        ChooseCmeanKnob                 matlab.ui.control.DiscreteKnob
        ApplyButton_2                   matlab.ui.control.Button
        FuzzyCmeansButton               matlab.ui.control.StateButton
        KmeansButton                    matlab.ui.control.StateButton
        ApplyButton                     matlab.ui.control.Button
        ChooseKmeanKnob                 matlab.ui.control.DiscreteKnob
        ChooseKmeanKnobLabel            matlab.ui.control.Label
        ChooseClusteringMethodLabel     matlab.ui.control.Label
        ChooseEdgeDetectionMethodLabel  matlab.ui.control.Label
        LaplacianofGaussianButton       matlab.ui.control.Button
        RobertsButton                   matlab.ui.control.Button
        PrewittButton                   matlab.ui.control.Button
        SobelButton                     matlab.ui.control.Button
        CannyButton                     matlab.ui.control.Button
        ChooseValueForTheThresholdEditFieldLabel  matlab.ui.control.Label
        ChooseThresholdMethodLabel      matlab.ui.control.Label
        ChooseValueForTheThresholdEditField  matlab.ui.control.NumericEditField
        AdaptiveThresholdingButton      matlab.ui.control.Button
        MeanButton_2                    matlab.ui.control.Button
        OtsuButton                      matlab.ui.control.Button
        FixedThresholdingButton         matlab.ui.control.StateButton
        ResetSaveImageLabel_2           matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ClusteringButton                matlab.ui.control.StateButton
        ThresholdingButton              matlab.ui.control.StateButton
        EdgeDetectionButton             matlab.ui.control.StateButton
        ChooseSegementationMethodLabel  matlab.ui.control.Label
        ResultingImageLabel             matlab.ui.control.Label
        SelectedImageLabel              matlab.ui.control.Label
        TakeSelectAnImageButton_2       matlab.ui.control.Button
        ChooseInputStyleLabel_2         matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ImageFilteringTab               matlab.ui.container.Tab
        ApplyFilterButton               matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        ChooseFilterLabel               matlab.ui.control.Label
        ResetSaveImageLabel_5           matlab.ui.control.Label
        SaveButton_3                    matlab.ui.control.Button
        ResetButton_4                   matlab.ui.control.Button
        TakeSelectAnImageButton_4       matlab.ui.control.Button
        ChooseInputStyleLabel_4         matlab.ui.control.Label
        Switch_4                        matlab.ui.control.Switch
        ResultingImageLabel_4           matlab.ui.control.Label
        SelectedImageLabel_4            matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputImg % Description
        outputImg % Description
        camera % Description
        switchValue = 0 % Description
        sliderValue % Description
        contrastChangingValue % Description
        tempImg % Description


        inputImg2 % Description
        outputImg2 % Description
        switchValue2 = 0 % Description

        KMValue = 1 % Description
        CMValue = 1 % Description


        inputImg3 % Description
        outputImg3 % Description
        switchValue3 = 0% Description
        middleImg % Description


        inputImg4 % Description
        outputImg4 % Description
        switchValue4 = 0 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakeSelectAnImageButton
        function SelectInputStyle(app, event)
            if isequal(app.switchValue,0)
                app.camera = webcam(1);
                app.camera.Resolution = '1280x720';

                figure(app.IPApplicationUIFigure);
                for c = 1:6
                    app.inputImg = snapshot(app.camera);
                end
                imshow(app.inputImg,'parent',app.UIAxes);

                clear('app.camera');
            else            
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg = imread(fullfile(path,file));
                imshow(app.inputImg,'parent',app.UIAxes);
            end
        end

        % Value changed function: Switch
        function SwitchValue(app, event)
            value = app.Switch.Value;

            if strcmp(value,'Browse')
                app.switchValue = 1;
            else
                app.switchValue = 0;
            end

        end

        % Callback function
        function SelectFilterStyle(app, event)
            if isequal(app.sliderValue,1)
                %img_double = im2double(app.inputImg);
                %gray_image = rgb2gray(app.inputImg);
                app.outputImg = imadjust(app.inputImg,[0.3 0.7],[]);
                imshow(app.outputImg,'parent',app.UIAxes_2);
            elseif isequal(app.sliderValue,2)          
                app.outputImg = imbalanced(app.inputImg,'ColorSpace','rgb','Ratio',[1 1 1]);
                imshow(app.outputImg,'parent',app.UIAxes_2);
            elseif isequal(app.sliderValue,3)
                
            end

        end

        % Value changing function: Slider_2
        function Contrast(app, event)
            changingValue = event.Value;
            app.outputImg = imadjust(app.inputImg,[0.2 changingValue],[0 1]);
            app.tempImg = imadjust(app.inputImg,[0.2 changingValue],[0 1]);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Value changing function: Slider_3
        function Saturation(app, event)
            changingValue = event.Value;
            img_hsv = rgb2hsv(app.inputImg); 
            img_hsv(:,:,2) = img_hsv(:,:,2) * changingValue;
            app.outputImg = hsv2rgb(img_hsv);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Value changing function: Slider_4
        function Hue(app, event)
            changingValue = event.Value;
            img_hsv = rgb2hsv(app.inputImg);
            img_hsv(:,:,1) = img_hsv(:,:,1) + changingValue;
            app.outputImg = hsv2rgb(img_hsv);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Value changing function: Slider_6
        function Brightness(app, event)
            changingValue = event.Value;
            img_hsv = rgb2hsv(app.inputImg);
            img_hsv(:,:,3) = img_hsv(:,:,3) * changingValue;
            app.outputImg = hsv2rgb(img_hsv);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Button pushed function: ResetButton
        function ResetInput(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
         
            app.inputImg = '';
            app.outputImg = '';
        end

        % Button pushed function: SaveButton
        function SaveOutput(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg, fullfile(path,file));
            end
        end

        % Button pushed function: TakeSelectAnImageButton_2
        function SelectInputStyle2(app, event)
            if isequal(app.switchValue2,0)
                app.camera = webcam(1);
                app.camera.Resolution = '1280x720';

                figure(app.IPApplicationUIFigure);
                
                for c = 1:6
                    app.inputImg2 = snapshot(app.camera);
                end
                
                imshow(app.inputImg2,'parent',app.UIAxes_4);

                clear('app.camera');
            else            
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg2 = imread(fullfile(path,file));
                imshow(app.inputImg2,'parent',app.UIAxes_4);
            end
        end

        % Value changed function: Switch_2
        function SwitchValue2(app, event)
            value = app.Switch_2.Value;
            
            if strcmp(value,'Browse')
                app.switchValue2 = 1;
            else
                app.switchValue2 = 0;
            end
        end

        % Button pushed function: SaveButton_2
        function SaveOutput2(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg2, fullfile(path,file));
            end
        end

        % Button pushed function: ResetButton_2
        function ResetInput2(app, event)
            cla(app.UIAxes_4);
            cla(app.UIAxes_3);
         
            app.inputImg2 = '';
            app.outputImg2 = '';
            clear('cam');
        end

        % Value changed function: EdgeDetectionButton
        function EdgeDetection(app, event)
            value_ED = app.EdgeDetectionButton.Value;
            
            
            if isequal(value_ED,1) 
                app.ThresholdingButton.Value = 0;

                app.ChooseThresholdMethodLabel.Visible = 'off';
                app.MeanButton_2.Visible = 'off';
                app.OtsuButton.Visible = 'off';
                app.AdaptiveThresholdingButton.Visible = 'off';
                app.FixedThresholdingButton.Value = 0;
                app.FixedThresholdingButton.Visible = 'off';
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';

                app.ClusteringButton.Value = 0;

                app.ChooseClusteringMethodLabel.Visible = 'off';
                app.KmeansButton.Visible = 'off';
                app.FuzzyCmeansButton.Visible = 'off';
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_ED,1)
                app.ChooseEdgeDetectionMethodLabel.Visible ="on";
                app.CannyButton.Visible = "on";
                app.SobelButton.Visible = "on";
                app.PrewittButton.Visible = "on";
                app.RobertsButton.Visible = "on";
                app.LaplacianofGaussianButton.Visible = "on";
            elseif isequal(value_ED,0)
                app.ChooseEdgeDetectionMethodLabel.Visible ="off";
                app.CannyButton.Visible = "off";
                app.SobelButton.Visible = "off";
                app.PrewittButton.Visible = "off";
                app.RobertsButton.Visible = "off";
                app.LaplacianofGaussianButton.Visible = "off";
            end

            
        end

        % Value changed function: ThresholdingButton
        function Thresholding(app, event)
            value_TH = app.ThresholdingButton.Value;
            
            if isequal(value_TH,1) 
                app.EdgeDetectionButton.Value = 0;

                app.ChooseEdgeDetectionMethodLabel.Visible ="off";
                app.CannyButton.Visible = "off";
                app.SobelButton.Visible = "off";
                app.PrewittButton.Visible = "off";
                app.RobertsButton.Visible = "off";
                app.LaplacianofGaussianButton.Visible = "off";

                app.ClusteringButton.Value = 0;
                
                app.ChooseClusteringMethodLabel.Visible = 'off';
                app.KmeansButton.Visible = 'off';
                app.FuzzyCmeansButton.Visible = 'off';
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_TH,1)
                app.ChooseThresholdMethodLabel.Visible = 'on';
                app.MeanButton_2.Visible = 'on';
                app.OtsuButton.Visible = 'on';
                app.AdaptiveThresholdingButton.Visible = 'on';
                app.FixedThresholdingButton.Visible = 'on';
            elseif isequal(value_TH,0)
                app.ChooseThresholdMethodLabel.Visible = 'off';
                app.MeanButton_2.Visible = 'off';
                app.OtsuButton.Visible = 'off';
                app.AdaptiveThresholdingButton.Visible = 'off';
                app.FixedThresholdingButton.Value = 0;
                app.FixedThresholdingButton.Visible = 'off';
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';
            end

            
        end

        % Value changed function: ClusteringButton
        function Clustering(app, event)
            value_CL = app.ClusteringButton.Value;
            
            if isequal(value_CL,1) 
                app.EdgeDetectionButton.Value = 0;
                
                app.ChooseEdgeDetectionMethodLabel.Visible ="off";
                app.CannyButton.Visible = "off";
                app.SobelButton.Visible = "off";
                app.PrewittButton.Visible = "off";
                app.RobertsButton.Visible = "off";
                app.LaplacianofGaussianButton.Visible = "off";

                app.ThresholdingButton.Value = 0;
                
                app.ChooseThresholdMethodLabel.Visible = 'off';
                app.MeanButton_2.Visible = 'off';
                app.OtsuButton.Visible = 'off';
                app.AdaptiveThresholdingButton.Visible = 'off';
                app.FixedThresholdingButton.Value = 0;
                app.FixedThresholdingButton.Visible = 'off';
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';

            end

            if isequal(value_CL,1)
                app.ChooseClusteringMethodLabel.Visible = 'on';
                app.KmeansButton.Visible = 'on';
                app.FuzzyCmeansButton.Visible = 'on';
                
            elseif isequal(value_CL,0)
                app.ChooseClusteringMethodLabel.Visible = 'off';
                app.KmeansButton.Visible = 'off';
                app.FuzzyCmeansButton.Visible = 'off';
                app.SelforganizingMapsButton.Visible = 'off';
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end
        end

        % Value changed function: FixedThresholdingButton
        function FixedThresholding(app, event)
            value_FTH = app.FixedThresholdingButton.Value;
            
            if isequal(value_FTH,1)
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'on';
                app.ChooseValueForTheThresholdEditField.Visible = 'on';
            elseif isequal(value_FTH,0)
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';
            end
            
        end

        % Value changed function: ChooseValueForTheThresholdEditField
        function FTH_Function(app, event)
            value = app.ChooseValueForTheThresholdEditField.Value;
            
            gray_image = rgb2gray(app.inputImg2);
            threshold_value = value;

            mask = gray_image < threshold_value;
            mask = imclearborder(mask);
            mask = bwareafilt(mask, 1);
            mask = ~bwareaopen(~mask, 1000);

            app.outputImg2 = mask;
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: MeanButton_2
        function Mean(app, event)
            gray_image = rgb2gray(app.inputImg2);
            mean_value = mean(gray_image(:));

            mask = gray_image < mean_value;
            mask = imclearborder(mask);
            mask = bwareafilt(mask, 1);
            mask = ~bwareaopen(~mask, 1000);

            app.outputImg2 = mask;
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: OtsuButton
        function Otsus(app, event)
            gray_image = rgb2gray(app.inputImg2);
            threshold_value = graythresh(gray_image);
            
            app.outputImg2 = imbinarize(gray_image,threshold_value);

            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: AdaptiveThresholdingButton
        function AdaptiveTH(app, event)
            gray_image = rgb2gray(app.inputImg2);
            %threshold_value = adaptthresh(app.inputImg2,0.4,'ForegroundPolarity','dark');
            enhanced_image = adapthisteq(gray_image);
            threshold_value = graythresh(enhanced_image);

            app.outputImg2 = imbinarize(gray_image,threshold_value);

            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: CannyButton
        function Canny(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"canny");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: SobelButton
        function Sobel(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"sobel");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: PrewittButton
        function Prewitt(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"prewitt");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: RobertsButton
        function Roberts(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"roberts");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button pushed function: LaplacianofGaussianButton
        function LOG(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"log");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Value changed function: KmeansButton
        function Kmeans(app, event)
            value_KM = app.KmeansButton.Value;

            if isequal(value_KM,1) 
                app.FuzzyCmeansButton.Value = 0;
                
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_KM,1)
                app.ChooseKmeanKnob.Visible = 'on';
                app.ApplyButton.Visible = 'on';
                app.ChooseKmeanKnobLabel.Visible = 'on';
            elseif isequal(value_KM,0)
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
            end
        end

        % Value changed function: FuzzyCmeansButton
        function FCM(app, event)
            value_FCM = app.FuzzyCmeansButton.Value;
            
            if isequal(value_FCM,1) 
                app.KmeansButton.Value = 0;
                
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_FCM,1)
                app.ChooseCmeanKnob.Visible = "on";
                app.ApplyButton_2.Visible = 'on';
                app.ChooseCmeanKnobLabel.Visible = 'on';
            elseif isequal(value_FCM,0)
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

        end

        % Value changed function: ChooseKmeanKnob
        function KmeansValue(app, event)
            value = app.ChooseKmeanKnob.Value;
            app.KMValue = int32(value);
            display(app.KMValue)
            display(class(app.KMValue))
        end

        % Button pushed function: ApplyButton
        function ApplyKM(app, event)
            gray_image = rgb2gray(app.inputImg2);
            L = imsegkmeans(gray_image,app.KMValue);
            app.outputImg2 = labeloverlay(app.inputImg2,L);
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Value changed function: ChooseCmeanKnob
        function FCMValue(app, event)
            value = app.ChooseCmeanKnob.Value;
            app.CMValue = int32(value);
            display(app.CMValue)
            display(class(app.CMValue))
        end

        % Button pushed function: ApplyButton_2
        function ApplyFCM(app, event)
            gray_image = double(rgb2gray(app.inputImg2));
            %gray_image = double(gray_image);
            app.outputImg2 = FCMforImage(gray_image, app.CMValue);
            imshow(app.outputImg2(:,:,app.CMValue),[],'parent',app.UIAxes_3);
        end

        % Button pushed function: TakeSelectAnImageButton_3
        function SelectInputStyle3(app, event)
            if isequal(app.switchValue3,0)
                app.camera = webcam(1);
                app.camera.Resolution = '1280x720';

                figure(app.IPApplicationUIFigure);
                
                for c = 1:6
                    app.inputImg3 = snapshot(app.camera);
                end
                
                imshow(app.inputImg3,'parent',app.UIAxes_5);

                clear('app.camera');
            else            
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg3 = imread(fullfile(path,file));
                imshow(app.inputImg3,'parent',app.UIAxes_5);
            end
        end

        % Value changed function: Switch_3
        function SwitchValue3(app, event)
            value = app.Switch_3.Value;
            
            if strcmp(value,'Browse')
                app.switchValue3 = 1;
            else
                app.switchValue3 = 0;
            end
        end

        % Button pushed function: ResetButton_3
        function ResetInput3(app, event)
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);

            app.inputImg3 = '';
            app.outputImg3 = '';
            clear('cam');
        end

        % Button pushed function: SaveNoisyImageButton
        function SaveOutput3(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.middleImg, fullfile(path,file));
            end
        end

        % Button pushed function: SPButton
        function SPNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'salt & pepper');
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Button pushed function: SaveResultingImageButton
        function SaveOutput4(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg3, fullfile(path,file));
            end
        end

        % Button pushed function: GaussianButton
        function GaussianNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'gaussian', 0, 0.01);
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Button pushed function: PoissonButton
        function PoissonNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'poisson');
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Button pushed function: SpeckleButton
        function SpeckleNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'speckle');
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Button pushed function: MedianFilterButton
        function Median(app, event)
            app.outputImg3 = medfilt2(app.middleImg);
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Button pushed function: WienerFilterButton
        function Wiener(app, event)
            app.outputImg3 = wiener2(app.middleImg,[5 5]);
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Button pushed function: ConstrainedLeastSquareFilterButton
        function CLSF(app, event)
            img_gray = rgb2gray(app.inputImg3);   % Coloured to Gray Conversion
            img_gray = im2double(img_gray);
            [X, Y] = size(img_gray);
            n_mean = 0;
            n_var = 0.001;
            gamma = 0.01;

            h_xy = fspecial('motion', 45, 45);
            blurred_img = imfilter(img_gray, h_xy, 'conv', 'circular');
            g_xy = imnoise(blurred_img, 'gaussian', n_mean, n_var);

            G_uv = fft2(g_xy);
            G_uv = fftshift(G_uv);
            
            H_uv = fft2(h_xy, X, Y);
            H_uv = fftshift(H_uv);
            
            H_uv_sq_abs = (abs(H_uv)).^2;

            lap_op_spatial=[0 -1 0; -1 4 -1; 0 -1 0];  % Defining a Laplacian Operator
            lap_op = fft2(lap_op_spatial, X, Y); % FFT of Lap Op Equal to the size of image
            lap_op = fftshift(lap_op);
            lap_op_abs_sq = abs(lap_op).^2;
            conj_H_uv = conj(H_uv);

            clsf_restored_img = G_uv.*(conj_H_uv./(H_uv_sq_abs + (gamma * lap_op_abs_sq))) ;

            XY = X*Y; % No of pixel elements
            noise = XY*(n_var + n_mean^2);
            ctr = 0; % to count number of iterations.
            % Iterating for Optimum Value of Gamma
            iteration_stopper = 0;
            accuracy_factor = 0.1;
            while iteration_stopper == 0 % ITERATIONS
                ctr = ctr + 1;
                % R(u,v) = G(u,v) - H(u,v)F_hat(u,v)
                R = abs(G_uv - (H_uv.*clsf_restored_img));
                % r(x,y) = ifft2(R(u,v)
                r = ifft2(R);
                %||r||^2 = Sum_M Sum_N (r(x,y)^2))
                r_sq = r.^2; % Computing the Square value
                r_sq = sum(abs(r_sq(:))); % Summing up the pixel values
                % Conditions Evaluation for Constrainst to be True/Valid
                if abs(r_sq - noise) < accuracy_factor % Condition - 1 (ALL OK)
                    iteration_stopper = 1;  % End loop
                else
                    if r_sq < noise % Condition - 2 (Increase Gamma)
                        gamma = gamma *(1 + accuracy_factor);
                    elseif r_sq > noise % Condition - 3 (Decrease Gamma)
                        gamma = gamma * (1 - accuracy_factor);
                    end
                    clsf_restored_img = G_uv.*(conj_H_uv./(H_uv_sq_abs + (gamma * lap_op_abs_sq)));
                end % End Iterations
            end % End while Loop
            app.outputImg3 = abs(ifft2(clsf_restored_img));
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Button pushed function: ResetButton_4
        function ResetInput4(app, event)
            cla(app.UIAxes_8);
            cla(app.UIAxes_9);

            app.inputImg4 = '';
            app.outputImg4 = '';
            clear('cam');
        end

        % Button pushed function: TakeSelectAnImageButton_4
        function SelectInputStyle4(app, event)
            if isequal(app.switchValue4,0)
                app.camera = webcam(1);
                app.camera.Resolution = '1280x720';

                figure(app.IPApplicationUIFigure);
                
                for c = 1:6
                    app.inputImg4 = snapshot(app.camera);
                end
                
                imshow(app.inputImg4,'parent',app.UIAxes_8);

                clear('app.camera');
            else            
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg4= imread(fullfile(path,file));
                imshow(app.inputImg4,'parent',app.UIAxes_8);
            end
        end

        % Value changed function: Switch_4
        function SwitchValue4(app, event)
            value = app.Switch_4.Value;
                        
            if strcmp(value,'Browse')
                app.switchValue4 = 1;
            else
                app.switchValue4 = 0;
            end
        end

        % Button pushed function: SaveButton_3
        function SaveOutput5(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg4, fullfile(path,file));
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilter(app, event)
            switch app.ListBox.Value
                case 'RGB2Gray'
                    app.outputImg4 = rgb2gray(app.inputImg4);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'RGB2HSV'
                    app.outputImg4 = rgb2hsv(app.inputImg4);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Motion Blur'
                    motion = fspecial('motion', 45, 45);
                    app.outputImg4 = imfilter(app.inputImg4, motion, 'conv', 'circular');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Gaussian Blur'
                    gaussian = fspecial('gaussian', [5,5], 2);
                    app.outputImg4 = imfilter(app.inputImg4, gaussian);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Averaging Blur'
                    average = ones(9)/81;
                    app.outputImg4 = imfilter(app.inputImg4, average);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Sharpen Filter'
                    sharpen = [0,-1,0;-1,5,-1;0,-1,0];
                    app.outputImg4 = imfilter(app.inputImg4, sharpen);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Emboss Filter'
                    emboss = [-2,-1,0;-1,1,1;0,1,2];
                    app.outputImg4 = imfilter(app.inputImg4, emboss);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Sobel Filter'
                    sob_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(sob_gray,'sobel');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Prewitt Filter'
                    pre_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(pre_gray,'prewitt');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Roberts Filter'
                    rob_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(rob_gray,'roberts');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'LOG Filter'
                    log_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(log_gray,'log');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Cartoon Filter'
                    cart_gray = rgb2gray(app.inputImg4);
                    median_image = medfilt2(cart_gray);
                    edge_image = edge(median_image, 'canny');
                    se = strel('square', 3);
                    dilated_image = imdilate(edge_image, se);
                    color_image = imcomplement(dilated_image);
                    color_image = im2double(color_image);
                    double_cartoon = im2double(app.inputImg4);
                    color_image = repmat(color_image, [1,1,3]);
                    app.outputImg4 = imlincomb(0.5, double_cartoon, 1, color_image);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Vignette Filter'
                    vig_gray = rgb2gray(app.inputImg4);
                    double_vig = im2double(app.inputImg4);
                    [rows, cols] = size(vig_gray);
                    center_x = ceil(cols/2);
                    center_y = ceil(rows/2);
                    [X,Y] = meshgrid(1:cols, 1:rows);
                    kernel = ones(rows, cols);
                    kernel(:) = sqrt((X - center_x).^2 + (Y - center_y).^2);
                    kernel = 1./(1 + kernel/mean(kernel(:)));
                    kernel = (kernel - min(kernel(:))) / (max(kernel(:)) - min(kernel(:)));
                    app.outputImg4 = imlincomb(1, double_vig, 0.7, double_vig .* repmat(kernel, [1,1,3]));
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Sepia Filter'
                    double_sep = im2double(app.inputImg4);
                    M = [0.393 0.769 0.189; 0.349 0.686 0.168; 0.272 0.534 0.131];
                    app.outputImg4 = im2double(reshape((M*reshape(permute(double_sep, [3 1 2]), 3, [])).', [size(double_sep,1) size(double_sep,2), 3]));

                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Negative Filter'
                    app.outputImg4 = imcomplement(app.inputImg4);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Solarize Filter'
                    double_sol = im2double(app.inputImg4);
                    filtered_image = double_sol;
                    filtered_image(double_sol > 0.5) = 1 - double_sol(double_sol > 0.5);
                    app.outputImg4 = filtered_image;
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Posterize Filter'
                    double_post = im2double(app.inputImg4);
                    filtered_image = double_post;

                    filtered_image(double_post > 0.75) = 1;
                    filtered_image(double_post > 0.5 & double_post <= 0.75) = 0.75;
                    filtered_image(double_post > 0.25 & double_post <= 0.5) = 0.5;
                    filtered_image(double_post <= 0.25) = 0.25;

                    app.outputImg4 = filtered_image;
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Salt & Pepper Noise'
                    sp_gray = rgb2gray(app.inputImg4);
                    sp_gray = im2double(sp_gray);
                    app.outputImg4 = imnoise(sp_gray, 'salt & pepper');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Gaussian Noise'
                    gau_gray = rgb2gray(app.inputImg4);
                    gau_gray = im2double(gau_gray);
                    app.outputImg4 = imnoise(gau_gray, 'gaussian');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Poisson Noise'
                    poi_gray = rgb2gray(app.inputImg4);
                    poi_gray = im2double(poi_gray);
                    app.outputImg4 = imnoise(poi_gray, 'poisson');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case 'Speckle Noise'
                    speck_gray = rgb2gray(app.inputImg4);
                    speck_gray = im2double(speck_gray);
                    app.outputImg4 = imnoise(speck_gray, 'speckle');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IPApplicationUIFigure = uifigure('Visible', 'off');
            app.IPApplicationUIFigure.Name = 'IP Application';
            app.TabGroup = uitabgroup(app.IPApplicationUIFigure);
            app.ImageAdjustmentTab = uitab(app.TabGroup);
            app.ImageAdjustmentTab.Title = 'Image Adjustment';
            app.UIAxes_2 = uiaxes(app.ImageAdjustmentTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes = uiaxes(app.ImageAdjustmentTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Switch = uiswitch(app.ImageAdjustmentTab, 'slider');
            app.Switch.Items = {'Camera', 'Browse'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValue, true);
            app.Switch.Value = 'Camera';
            app.ChooseInputStyleLabel = uilabel(app.ImageAdjustmentTab);
            app.ChooseInputStyleLabel.Text = 'Choose Input Style';
            app.AdjustTheImageLabel = uilabel(app.ImageAdjustmentTab);
            app.AdjustTheImageLabel.Text = 'Adjust The Image';
            app.TakeSelectAnImageButton = uibutton(app.ImageAdjustmentTab, 'push');
            app.TakeSelectAnImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle, true);
            app.TakeSelectAnImageButton.Text = 'Take / Select An Image';
            app.ResetButton = uibutton(app.ImageAdjustmentTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetInput, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.ImageAdjustmentTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput, true);
            app.SaveButton.Text = 'Save';
            app.Slider_2 = uislider(app.ImageAdjustmentTab);
            app.Slider_2.Limits = [0.3 1];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Contrast, true);
            app.Slider_2.Value = 0.3;
            app.ContrastLabel = uilabel(app.ImageAdjustmentTab);
            app.ContrastLabel.Text = 'Contrast';
            app.SaturationLabel = uilabel(app.ImageAdjustmentTab);
            app.SaturationLabel.Text = 'Saturation';
            app.Slider_3 = uislider(app.ImageAdjustmentTab);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Saturation, true);
            app.Slider_4 = uislider(app.ImageAdjustmentTab);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Hue, true);
            app.Slider_6 = uislider(app.ImageAdjustmentTab);
            app.Slider_6.Limits = [0 1];
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Brightness, true);
            app.HueLabel = uilabel(app.ImageAdjustmentTab);
            app.HueLabel.Text = 'Hue';
            app.BrightnessLabel = uilabel(app.ImageAdjustmentTab);
            app.BrightnessLabel.Text = 'Brightness';
            app.SelectedImageLabel_2 = uilabel(app.ImageAdjustmentTab);
            app.SelectedImageLabel_2.Text = 'Selected Image';
            app.ResultingImageLabel_2 = uilabel(app.ImageAdjustmentTab);
            app.ResultingImageLabel_2.Text = 'Resulting Image';
            app.ResetSaveImageLabel_3 = uilabel(app.ImageAdjustmentTab);
            app.ResetSaveImageLabel_3.Text = 'Reset / Save Image';
            app.ImageRestorationTab = uitab(app.TabGroup);
            app.ImageRestorationTab.Title = 'Image Restoration';
            app.UIAxes_5 = uiaxes(app.ImageRestorationTab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.ImageRestorationTab);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.ImageRestorationTab);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.Visible = 'off';
            app.Switch_3 = uiswitch(app.ImageRestorationTab, 'slider');
            app.Switch_3.Items = {'Camera', 'Browse'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @SwitchValue3, true);
            app.Switch_3.Value = 'Camera';
            app.ChooseInputStyleLabel_3 = uilabel(app.ImageRestorationTab);
            app.ChooseInputStyleLabel_3.Text = 'Choose Input Style';
            app.TakeSelectAnImageButton_3 = uibutton(app.ImageRestorationTab, 'push');
            app.TakeSelectAnImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle3, true);
            app.TakeSelectAnImageButton_3.Text = 'Take / Select An Image';
            app.ChooseNoiseStyleLabel = uilabel(app.ImageRestorationTab);
            app.ChooseNoiseStyleLabel.Text = '1. Choose Noise Style';
            app.ChooseRestorationMethodLabel = uilabel(app.ImageRestorationTab);
            app.ChooseRestorationMethodLabel.Text = '2. Choose Restoration Method';
            app.ResetButton_3 = uibutton(app.ImageRestorationTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetInput3, true);
            app.ResetButton_3.Text = 'Reset';
            app.SaveNoisyImageButton = uibutton(app.ImageRestorationTab, 'push');
            app.SaveNoisyImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput3, true);
            app.SaveNoisyImageButton.Text = 'Save Noisy Image';
            app.ResetSaveImageLabel_4 = uilabel(app.ImageRestorationTab);
            app.ResetSaveImageLabel_4.Text = 'Reset / Save Image';
            app.SPButton = uibutton(app.ImageRestorationTab, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPNoise, true);
            app.SPButton.Text = 'S&P';
            app.GaussianButton = uibutton(app.ImageRestorationTab, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoise, true);
            app.GaussianButton.Text = 'Gaussian';
            app.PoissonButton = uibutton(app.ImageRestorationTab, 'push');
            app.PoissonButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonNoise, true);
            app.PoissonButton.Text = 'Poisson';
            app.SpeckleButton = uibutton(app.ImageRestorationTab, 'push');
            app.SpeckleButton.ButtonPushedFcn = createCallbackFcn(app, @SpeckleNoise, true);
            app.SpeckleButton.Text = 'Speckle';
            app.SaveResultingImageButton = uibutton(app.ImageRestorationTab, 'push');
            app.SaveResultingImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput4, true);
            app.SaveResultingImageButton.Text = 'Save Resulting Image';
            app.WienerFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.WienerFilterButton.ButtonPushedFcn = createCallbackFcn(app, @Wiener, true);
            app.WienerFilterButton.Text = 'Wiener Filter';
            app.MedianFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @Median, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.ConstrainedLeastSquareFilterButton = uibutton(app.ImageRestorationTab, 'push');
            app.ConstrainedLeastSquareFilterButton.ButtonPushedFcn = createCallbackFcn(app, @CLSF, true);
            app.ConstrainedLeastSquareFilterButton.Text = 'Constrained Least Square Filter';
            app.SelectedImageLabel_3 = uilabel(app.ImageRestorationTab);
            app.SelectedImageLabel_3.Text = 'Selected Image';
            app.NoisyImageLabel = uilabel(app.ImageRestorationTab);
            app.NoisyImageLabel.Text = 'Noisy Image';
            app.ResultingImageLabel_3 = uilabel(app.ImageRestorationTab);
            app.ResultingImageLabel_3.Text = 'Resulting Image';
            app.ImageSegmentationTab = uitab(app.TabGroup);
            app.ImageSegmentationTab.Title = 'Image Segmentation';
            app.UIAxes_3 = uiaxes(app.ImageSegmentationTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.ImageSegmentationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Visible = 'off';
            app.Switch_2 = uiswitch(app.ImageSegmentationTab, 'slider');
            app.Switch_2.Items = {'Camera', 'Browse'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @SwitchValue2, true);
            app.Switch_2.Value = 'Camera';
            app.ChooseInputStyleLabel_2 = uilabel(app.ImageSegmentationTab);
            app.ChooseInputStyleLabel_2.Text = 'Choose Input Style';
            app.TakeSelectAnImageButton_2 = uibutton(app.ImageSegmentationTab, 'push');
            app.TakeSelectAnImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle2, true);
            app.TakeSelectAnImageButton_2.Text = 'Take / Select An Image';
            app.SelectedImageLabel = uilabel(app.ImageSegmentationTab);
            app.SelectedImageLabel.Text = 'Selected Image';
            app.ResultingImageLabel = uilabel(app.ImageSegmentationTab);
            app.ResultingImageLabel.Text = 'Resulting Image';
            app.ChooseSegementationMethodLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseSegementationMethodLabel.Text = 'Choose Segementation Method';
            app.EdgeDetectionButton = uibutton(app.ImageSegmentationTab, 'state');
            app.EdgeDetectionButton.ValueChangedFcn = createCallbackFcn(app, @EdgeDetection, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.ThresholdingButton = uibutton(app.ImageSegmentationTab, 'state');
            app.ThresholdingButton.ValueChangedFcn = createCallbackFcn(app, @Thresholding, true);
            app.ThresholdingButton.Text = 'Thresholding';
            app.ClusteringButton = uibutton(app.ImageSegmentationTab, 'state');
            app.ClusteringButton.ValueChangedFcn = createCallbackFcn(app, @Clustering, true);
            app.ClusteringButton.Text = 'Clustering';
            app.ResetButton_2 = uibutton(app.ImageSegmentationTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetInput2, true);
            app.ResetButton_2.Text = 'Reset';
            app.SaveButton_2 = uibutton(app.ImageSegmentationTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput2, true);
            app.SaveButton_2.Text = 'Save';
            app.ResetSaveImageLabel_2 = uilabel(app.ImageSegmentationTab);
            app.ResetSaveImageLabel_2.Text = 'Reset / Save Image';
            app.FixedThresholdingButton = uibutton(app.ImageSegmentationTab, 'state');
            app.FixedThresholdingButton.ValueChangedFcn = createCallbackFcn(app, @FixedThresholding, true);
            app.FixedThresholdingButton.Visible = 'off';
            app.FixedThresholdingButton.Text = 'Fixed Thresholding';
            app.OtsuButton = uibutton(app.ImageSegmentationTab, 'push');
            app.OtsuButton.ButtonPushedFcn = createCallbackFcn(app, @Otsus, true);
            app.OtsuButton.Visible = 'off';
            app.OtsuButton.Text = 'Otsu ';
            app.MeanButton_2 = uibutton(app.ImageSegmentationTab, 'push');
            app.MeanButton_2.ButtonPushedFcn = createCallbackFcn(app, @Mean, true);
            app.MeanButton_2.Visible = 'off';
            app.MeanButton_2.Text = 'Mean';
            app.AdaptiveThresholdingButton = uibutton(app.ImageSegmentationTab, 'push');
            app.AdaptiveThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveTH, true);
            app.AdaptiveThresholdingButton.Visible = 'off';
            app.AdaptiveThresholdingButton.Text = 'Adaptive Thresholding';
            app.ChooseValueForTheThresholdEditField = uieditfield(app.ImageSegmentationTab, 'numeric');
            app.ChooseValueForTheThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @FTH_Function, true);
            app.ChooseValueForTheThresholdEditField.Visible = 'off';
            app.ChooseThresholdMethodLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseThresholdMethodLabel.Visible = 'off';
            app.ChooseThresholdMethodLabel.Text = 'Choose Threshold Method';
            app.ChooseValueForTheThresholdEditFieldLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
            app.ChooseValueForTheThresholdEditFieldLabel.Text = 'Choose Value For The Threshold';
            app.CannyButton = uibutton(app.ImageSegmentationTab, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @Canny, true);
            app.CannyButton.Visible = 'off';
            app.CannyButton.Text = 'Canny';
            app.SobelButton = uibutton(app.ImageSegmentationTab, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @Sobel, true);
            app.SobelButton.Visible = 'off';
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uibutton(app.ImageSegmentationTab, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @Prewitt, true);
            app.PrewittButton.Visible = 'off';
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uibutton(app.ImageSegmentationTab, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @Roberts, true);
            app.RobertsButton.Visible = 'off';
            app.RobertsButton.Text = 'Roberts';
            app.LaplacianofGaussianButton = uibutton(app.ImageSegmentationTab, 'push');
            app.LaplacianofGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @LOG, true);
            app.LaplacianofGaussianButton.Visible = 'off';
            app.LaplacianofGaussianButton.Text = 'Laplacian of Gaussian';
            app.ChooseEdgeDetectionMethodLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseEdgeDetectionMethodLabel.Visible = 'off';
            app.ChooseEdgeDetectionMethodLabel.Text = 'Choose Edge Detection Method';
            app.ChooseClusteringMethodLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseClusteringMethodLabel.Visible = 'off';
            app.ChooseClusteringMethodLabel.Text = 'Choose Clustering Method';
            app.ChooseKmeanKnobLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseKmeanKnobLabel.Visible = 'off';
            app.ChooseKmeanKnobLabel.Text = 'Choose K-mean';
            app.ChooseKmeanKnob = uiknob(app.ImageSegmentationTab, 'discrete');
            app.ChooseKmeanKnob.Items = {'1', '2', '3', '4'};
            app.ChooseKmeanKnob.ItemsData = [1 2 3 4];
            app.ChooseKmeanKnob.ValueChangedFcn = createCallbackFcn(app, @KmeansValue, true);
            app.ChooseKmeanKnob.Visible = 'off';
            app.ChooseKmeanKnob.Value = 1;
            app.ApplyButton = uibutton(app.ImageSegmentationTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyKM, true);
            app.ApplyButton.Visible = 'off';
            app.ApplyButton.Text = 'Apply';
            app.KmeansButton = uibutton(app.ImageSegmentationTab, 'state');
            app.KmeansButton.ValueChangedFcn = createCallbackFcn(app, @Kmeans, true);
            app.KmeansButton.Visible = 'off';
            app.KmeansButton.Text = 'K-means';
            app.FuzzyCmeansButton = uibutton(app.ImageSegmentationTab, 'state');
            app.FuzzyCmeansButton.ValueChangedFcn = createCallbackFcn(app, @FCM, true);
            app.FuzzyCmeansButton.Visible = 'off';
            app.FuzzyCmeansButton.Text = 'Fuzzy C-means';
            app.ApplyButton_2 = uibutton(app.ImageSegmentationTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyFCM, true);
            app.ApplyButton_2.Visible = 'off';
            app.ApplyButton_2.Text = 'Apply';
            app.ChooseCmeanKnob = uiknob(app.ImageSegmentationTab, 'discrete');
            app.ChooseCmeanKnob.Items = {'1', '2', '3', '4'};
            app.ChooseCmeanKnob.ItemsData = [1 2 3 4];
            app.ChooseCmeanKnob.ValueChangedFcn = createCallbackFcn(app, @FCMValue, true);
            app.ChooseCmeanKnob.Visible = 'off';
            app.ChooseCmeanKnob.Value = 1;
            app.ChooseCmeanKnobLabel = uilabel(app.ImageSegmentationTab);
            app.ChooseCmeanKnobLabel.Visible = 'off';
            app.ChooseCmeanKnobLabel.Text = 'Choose C-mean';
            app.ImageFilteringTab = uitab(app.TabGroup);
            app.ImageFilteringTab.Title = 'Image Filtering';
            app.UIAxes_8 = uiaxes(app.ImageFilteringTab);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.Visible = 'off';
            app.UIAxes_9 = uiaxes(app.ImageFilteringTab);
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_9.Visible = 'off';
            app.SelectedImageLabel_4 = uilabel(app.ImageFilteringTab);
            app.SelectedImageLabel_4.Text = 'Selected Image';
            app.ResultingImageLabel_4 = uilabel(app.ImageFilteringTab);
            app.ResultingImageLabel_4.Text = 'Resulting Image';
            app.Switch_4 = uiswitch(app.ImageFilteringTab, 'slider');
            app.Switch_4.Items = {'Camera', 'Browse'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @SwitchValue4, true);
            app.Switch_4.Value = 'Camera';
            app.ChooseInputStyleLabel_4 = uilabel(app.ImageFilteringTab);
            app.ChooseInputStyleLabel_4.Text = 'Choose Input Style';
            app.TakeSelectAnImageButton_4 = uibutton(app.ImageFilteringTab, 'push');
            app.TakeSelectAnImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle4, true);
            app.TakeSelectAnImageButton_4.Text = 'Take / Select An Image';
            app.ResetButton_4 = uibutton(app.ImageFilteringTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetInput4, true);
            app.ResetButton_4.Text = 'Reset';
            app.SaveButton_3 = uibutton(app.ImageFilteringTab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput5, true);
            app.SaveButton_3.Text = 'Save';
            app.ResetSaveImageLabel_5 = uilabel(app.ImageFilteringTab);
            app.ResetSaveImageLabel_5.Text = 'Reset / Save Image';
            app.ChooseFilterLabel = uilabel(app.ImageFilteringTab);
            app.ChooseFilterLabel.Text = 'Choose Filter';
            app.ListBox = uilistbox(app.ImageFilteringTab);
            app.ListBox.Items = {'RGB2Gray', 'RGB2HSV', 'Negative Filter', 'Sepia Filter', 'Vignette Filter', 'Solarize Filter', 'Posterize Filter', 'Gaussian Noise', 'Cartoon Filter', 'Emboss Filter', 'Sharpen Filter', 'Motion Blur', 'Gaussian Blur', 'Averaging Blur', 'Sobel Filter', 'Prewitt Filter', 'Roberts Filter', 'LOG Filter', 'Salt & Pepper Noise', 'Poisson Noise', 'Speckle Noise'};
            app.ListBox.Value = 'RGB2Gray';
            app.ApplyFilterButton = uibutton(app.ImageFilteringTab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilter, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ImageProcessingApplicationLabel = uilabel(app.IPApplicationUIFigure);
            app.ImageProcessingApplicationLabel.Text = 'Image Processing Application';
            % Show the figure after all components are created
            app.IPApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IPApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IPApplicationUIFigure)
        end
    end
end